classdef S20App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        RiderEditFieldLabel           matlab.ui.control.Label
        RiderEditField                matlab.ui.control.NumericEditField
        RouteEditFieldLabel           matlab.ui.control.Label
        RouteEditField                matlab.ui.control.NumericEditField
        PushToRunButton               matlab.ui.control.Button
        ElevationLabel                matlab.ui.control.Label
        CourseButtonGroup             matlab.ui.container.ButtonGroup
        Cuhrb_DPButton                matlab.ui.control.RadioButton
        Masto_DPButton                matlab.ui.control.RadioButton
        MTU_midButton                 matlab.ui.control.RadioButton
        Nara_DPButton                 matlab.ui.control.RadioButton
        swedetown_DPButton            matlab.ui.control.RadioButton
        PPAEditFieldLabel             matlab.ui.control.Label
        PPAEditField                  matlab.ui.control.EditField
        EditFieldLabel                matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        VPREditFieldLabel             matlab.ui.control.Label
        VPREditField                  matlab.ui.control.NumericEditField
        TotalTimeEditFieldLabel       matlab.ui.control.Label
        TotalTimeEditField            matlab.ui.control.NumericEditField
        CaloriesBurnedEditFieldLabel  matlab.ui.control.Label
        CaloriesBurnedEditField       matlab.ui.control.EditField
        AvgSpeedEditFieldLabel        matlab.ui.control.Label
        AvgSpeedEditField             matlab.ui.control.EditField
        TotalDistanceEditFieldLabel   matlab.ui.control.Label
        TotalDistanceEditField        matlab.ui.control.EditField
        UIAxes3                       matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function[lat, long, elevation, distance, HR, speed, results, Weight] = RiderSelect(app)
            input = (app.RiderEditField.Value);
            data = 0;
            Weight = 0;
            if input == 1
                data = xlsread('tt_rider01.xlsx');
                Weight = 163.2;
            elseif input == 2
                data = xlsread('tt_rider02.xlsx');
                Weight = 164.2;
            elseif input == 3
                data = xlsread('tt_rider03.xlsx');
                Weight = 170;
            elseif input == 4
                data = xlsread('tt_rider04.xlsx');
                Weight = 157.0;
            elseif input == 5
                data = xlsread('tt_rider05.xlsx');
                Weight = 162.4;
            elseif input == 6
                data = xlsread('tt_rider06.xlsx');
                Weight = 168.4;
            elseif input == 7
                data = xlsread('tt_rider07.xlsx');
                Weight = 170.8;
            elseif input == 8
                data = xlsread('tt_rider08.xlsx');
                Weight = 172.4;
            elseif input == 9
                data = xlsread('tt_rider09.xlsx');
                Weight = 162.4;
            elseif input == 10
                data = xlsread('tt_rider10.xlsx');
                Weight = 181.6;
            end
            lat = data(:,1);
            long = data(:,2);
            elevation = data(:,3);
            distance = data(:,4);
            HR = data(:,5);
            speed = data(:,6);
            results = data;
            
        end
        
        function[lat, long, elevation, distance, results] = RouteData(app)
            %input = str2num(app.RouteEditField.Value);
            data = 0;
            if app.Cuhrb_DPButton.Value == 1
                data = load('Cuhrb_DP.dat');
            elseif app.Masto_DPButton.Value == 1
                data = load('Masto_DP.dat');
            elseif app.MTU_midButton.Value == 1
                data = load('MTU_mid_DP.dat');
            elseif app.Nara_DPButton.Value == 1
                data = load('Nara_DP.dat');
            elseif app.swedetown_DPButton.Value == 1
                data = load('swedetown_DP.dat');
            end
            lat = data(:,1);
            long = data(:,2);
            elevation = data(:,3);
            distance = data(:,4);
            results = data;
        end
        
        function dt = findTime(app, distance, speed)
            dt = 0;
            for t = 2:length(distance)
                if((distance(t)-distance(t-1)) == 0)
                   dt(t) = dt(t-1);
                else
                dt(t) = (distance(t)-distance(t-1))/(speed(t));
                end                
            end            
        end
        
        function slope = slopeCalc(app, elevation, distance)
            for t = 2:length(distance)
                slope(t) = atand((elevation(t)-elevation(t-1))/(distance(t)-distance(t-1)));
            end
        end
        
        function totalElevation = elevationGain(app, elevation)
            totalElevation = 0;
            for t = 2:length(elevation)
                if elevation(t) > elevation(t-1) 
                    totalElevation = totalElevation + (elevation(t) - elevation(t-1));
                end
            end
        end
        
    end


    methods (Access = private)

        % Button pushed function: PushToRunButton
        function PushToRunButtonPushed(app, event)
            value = app.RiderEditField.Value; 
            if(value<0||value>11||fix(value)~=value)
                ridernumber = 'Try Again. Enter a number 1-10';
            else            
                [lat, long, elevation, distance, HR, speed, results, Weight] = RiderSelect(app);
                [Clat, Clong, Celevation, Cdistance, Cresults] = RouteData(app);
                dt = findTime(app, distance, speed);
                slope = slopeCalc(app, elevation, distance);
                totalElevation = elevationGain(app, elevation);
            
                plot3(app.UIAxes, Clat, Clong, Celevation);
                totalTime = dt(1);
                for t = 2:length(dt)
                    totalTime(t) = dt(t) + totalTime(t-1);
                end
                Kg = (Weight*4.45) / 9.81;
                FAir = F_Air(speed, .36);
                FRoll = F_roll(slope, Kg);
                FBump = F_bump(50,speed);
                FSlope = F_slope(Kg, slope);
                acc = calcacceleration(speed, dt);
                Frider = calcFrider(Kg, acc, FAir, FSlope, FRoll, FBump);
                PPA = calcPPA(Frider,speed);
                app.PPAEditField.Value = num2str(PPA);
            
                %% VPR CALCULATIONS
            
                VPR = 0;
                adt = 0;
                slope = slopeCalc(app, Celevation, Cdistance);
                while(VPR <= PPA *.95 || VPR >= PPA * 1.05)
                    if(VPR <= PPA *.95)
                        adt = adt - .01; 
                    elseif(VPR >= PPA * 1.05)
                        adt = adt + .01;
                    end
                    speed = 0;
                    for N = 2:length(Cdistance)
                        speed(N) = (Cdistance(N) - Cdistance(N-1)) / adt;
                    end
                    VFAir = F_Air(speed,.36);
                    VFRoll = F_roll(slope, Kg);
                    VFBump = F_bump(50,speed);
                    VFSlope = F_slope(Kg, slope);
                    for i = 1:length(speed) - 1
                        accel(i) = (speed(i + 1) - speed(i)) / adt;
                    end
                    accel(length(slope)) = 0;
                    VFrider = calcFrider(Kg, accel, VFAir, VFSlope, VFRoll, VFBump);
                    [VPR,pride] = calcPPA(VFrider,speed);
                end     
                
                
                avgspeed= 0;
                for N = 1:length(speed)
                    avgspeed = avgspeed + speed(i); 
                end
                avgspeed = avgspeed / length(speed);
                app.AvgSpeedEditField.Value = num2str(avgspeed);
                
                dis = Cdistance(length(Cdistance));
                app.TotalDistanceEditField.Value = num2str(dis);
                
                totaltime = adt * length(speed);
                app.TotalTimeEditField.Value = totaltime;
                pride(length(Cdistance)) = 0;
                app.VPREditField.Value = VPR;
                plot(app.UIAxes2, Cdistance, pride);
                
                calories = (VPR * totaltime) / 4184 ;
                app.CaloriesBurnedEditField.Value = num2str(calories);
                
                plot(app.UIAxes3, Cdistance, speed);
                
            end
            
            
        end

        % Value changed function: RiderEditField
        function RiderEditFieldValueChanged(app, event)
            value = app.RiderEditField.Value;
            while value<1||value>10||fix(value)~=value

            ridernumber = 'Try Again. Enter a number 1-10';

            app.EditField.Value = ridernumber;

            return

            end
            app.EditField.Value = '';
            PushToRunButtonPushed(app, event);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Latitude / Longitude')
            xlabel(app.UIAxes, 'Latitude')
            ylabel(app.UIAxes, 'Longitude')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Distance vs Power')
            xlabel(app.UIAxes2, ' Distance (m)')
            ylabel(app.UIAxes2, 'Power (joules)')
            app.RiderEditFieldLabel = uilabel(app.UIFigure);
            app.RiderEditFieldLabel.Text = 'Rider';
            app.RiderEditField = uieditfield(app.UIFigure, 'numeric');
            app.RiderEditField.ValueChangedFcn = createCallbackFcn(app, @RiderEditFieldValueChanged, true);
            app.RiderEditField.Value = 1;
            app.RouteEditFieldLabel = uilabel(app.UIFigure);
            app.RouteEditFieldLabel.Text = 'Route';
            app.RouteEditField = uieditfield(app.UIFigure, 'numeric');
            app.RouteEditField.Value = 1;
            app.PushToRunButton = uibutton(app.UIFigure, 'push');
            app.PushToRunButton.ButtonPushedFcn = createCallbackFcn(app, @PushToRunButtonPushed, true);
            app.PushToRunButton.Text = 'Push To Run ';
            app.ElevationLabel = uilabel(app.UIFigure);
            app.ElevationLabel.Text = 'Elevation';
            app.CourseButtonGroup = uibuttongroup(app.UIFigure);
            app.CourseButtonGroup.Title = 'Course';
            app.Cuhrb_DPButton = uiradiobutton(app.CourseButtonGroup);
            app.Cuhrb_DPButton.Text = 'Cuhrb_DP';
            app.Cuhrb_DPButton.Value = true;
            app.Masto_DPButton = uiradiobutton(app.CourseButtonGroup);
            app.Masto_DPButton.Text = 'Masto_DP';
            app.MTU_midButton = uiradiobutton(app.CourseButtonGroup);
            app.MTU_midButton.Text = 'MTU_mid';
            app.Nara_DPButton = uiradiobutton(app.CourseButtonGroup);
            app.Nara_DPButton.Text = 'Nara_DP';
            app.swedetown_DPButton = uiradiobutton(app.CourseButtonGroup);
            app.swedetown_DPButton.Text = 'swedetown_DP';
            app.PPAEditFieldLabel = uilabel(app.UIFigure);
            app.PPAEditFieldLabel.Text = 'PPA';
            app.PPAEditField = uieditfield(app.UIFigure, 'text');
            app.PPAEditField.Editable = 'off';
            app.PPAEditField.Value = '0';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.VPREditFieldLabel = uilabel(app.UIFigure);
            app.VPREditFieldLabel.Text = 'VPR';
            app.VPREditField = uieditfield(app.UIFigure, 'numeric');
            app.VPREditField.Editable = 'off';
            app.TotalTimeEditFieldLabel = uilabel(app.UIFigure);
            app.TotalTimeEditFieldLabel.Text = 'Total Time';
            app.TotalTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalTimeEditField.Editable = 'off';
            app.CaloriesBurnedEditFieldLabel = uilabel(app.UIFigure);
            app.CaloriesBurnedEditFieldLabel.Text = 'Calories Burned';
            app.CaloriesBurnedEditField = uieditfield(app.UIFigure, 'text');
            app.CaloriesBurnedEditField.Editable = 'off';
            app.CaloriesBurnedEditField.Value = '0';
            app.AvgSpeedEditFieldLabel = uilabel(app.UIFigure);
            app.AvgSpeedEditFieldLabel.Text = 'Avg. Speed';
            app.AvgSpeedEditField = uieditfield(app.UIFigure, 'text');
            app.AvgSpeedEditField.Editable = 'off';
            app.AvgSpeedEditField.Value = '0';
            app.TotalDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.TotalDistanceEditFieldLabel.Text = 'Total Distance';
            app.TotalDistanceEditField = uieditfield(app.UIFigure, 'text');
            app.TotalDistanceEditField.Editable = 'off';
            app.TotalDistanceEditField.Value = '0';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Distance vs Velocity')
            xlabel(app.UIAxes3, 'Distance (m)')
            ylabel(app.UIAxes3, 'Velocity (m/s)')
        end
    end

    methods (Access = public)

        % Construct app
        function app = S20App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end